classdef ILT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SelectScannerDropDown    matlab.ui.control.DropDown
        ScannerLabel             matlab.ui.control.Label
        Scanning                 matlab.ui.container.ButtonGroup
        yesButton                matlab.ui.control.RadioButton
        noButton                 matlab.ui.control.RadioButton
        SelectAlcoholDropDown    matlab.ui.control.DropDown
        AlcoholLabel             matlab.ui.control.Label
        SelectJuiceDropDown      matlab.ui.control.DropDown
        JuiceLabel               matlab.ui.control.Label
        SelectTaskDropDown       matlab.ui.control.DropDown
        SelecttaskLabel          matlab.ui.control.Label
        RunExperimentButton      matlab.ui.control.Button
        SequenceButton           matlab.ui.container.ButtonGroup
        SAButton                 matlab.ui.control.RadioButton
        ASButton                 matlab.ui.control.RadioButton
        default1                 matlab.ui.control.RadioButton
        GroupButton              matlab.ui.container.ButtonGroup
        default2                 matlab.ui.control.RadioButton
        ControlButton            matlab.ui.control.RadioButton
        AUDButton                matlab.ui.control.RadioButton
        SubjectIDEditField       matlab.ui.control.EditField
        SubjectIDEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BrowseButtonPushed(app, event)
            workdir = uigetdir; % get directory location
            app.UIFigure.Visible = 'off'; % workaround so that figure doesn't get sent to the background
            app.UIFigure.Visible = 'on';
            app.directory.Text = workdir;
            
            cd(workdir) % go to task directory
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            
            % return all subsequent values to default if subject ID is changed
            app.default1.Value = true;
            % app.default3.Value = true;
            app.default2.Value = true;
            app.SelectTaskDropDown.Value='999';
            app.SelectJuiceDropDown.Value='999';
            app.SelectAlcoholDropDown.Value='999';
            app.SelectScannerDropDown.Value='999';


            %% enable all subject-specific parameters if ID is changed
            
            app.AUDButton.Enable='on'; 
            app.ControlButton.Enable='on'; 
            app.ASButton.Enable='on'; 
            app.SAButton.Enable='on'; 
            % app.BAButton.Enable='on'; 
            % app.ABButton.Enable='on'; 
            app.SelectTaskDropDown.Enable='on'; 
            app.SelectJuiceDropDown.Enable='on'; 
            app.SelectAlcoholDropDown.Enable='on'; 
            app.SelectScannerDropDown.Enable='on';


        end

        % Button pushed function: RunExperimentButton
        function RunExperimentButtonPushed(app, event)
            
            % get subjn
            subjn = app.SubjectIDEditField.Value;
            
            
            % get group
            if app.AUDButton.Value
                type='AUD';
            elseif app.ControlButton.Value
                type='HC';
            end


            % get Juice
            chosen_J=app.SelectJuiceDropDown.Value;
            
             % get Alcohol
            chosen_A=app.SelectAlcoholDropDown.Value;
            
            % get Task
            session=app.SelectTaskDropDown.Value;

            % get block sequence
            if app.ASButton.Value
                Reihenfolge='A-S';
            elseif app.SAButton.Value
                Reihenfolge='S-A';
            end
     
            
            % get scanning mode
             if app.yesButton.Value
                doscanner=1;
            elseif app.noButton.Value
                doscanner=0;
            end
            %   if app.BerlinButton.Value
            %       exploc='b';
            %   elseif app.DresdenButton.Value
            %       exploc='d';
            %   end

            % get Scanner
            whichscanner=str2num(app.SelectScannerDropDown.Value);
            
            % save everything in file guiparams, which will be loaded in
            % runexperiment to set subject-specific experimental parameters
            eval('save guiparams subjn doscanner Reihenfolge type chosen_J chosen_A session whichscanner');
            
            %% disable all subject-specific parameters after Run Experiment
            % Button is pushed (this is just for conistency, i.e. changing
            % the values after exppart1 would not alter the parameters in the
            % experiment, as they are read in from exppart1)
            
            app.AUDButton.Enable='off'; 
            app.ControlButton.Enable='off'; 
            app.ASButton.Enable='off'; 
            app.SAButton.Enable='off'; 
            % app.ABButton.Enable='off'; 
            % app.BAButton.Enable='off'; 


            % run experiment
            runexp_ilt;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.GroupButton = uibuttongroup(app.UIFigure);
            app.GroupButton.Title = 'Group';
            app.AUDButton = uiradiobutton(app.GroupButton);
            app.AUDButton.Text = 'AUD';
            app.ControlButton = uiradiobutton(app.GroupButton);
            app.ControlButton.Text = 'Control';
            app.default2 = uiradiobutton(app.GroupButton);
            app.default2.Enable = 'off';
            app.default2.Visible = 'off';
            app.default2.Text = 'initial';
            app.default2.Value = true;
            app.SequenceButton = uibuttongroup(app.UIFigure);
            app.SequenceButton.Title = 'Alc / Juice Sequence';
            app.default1 = uiradiobutton(app.SequenceButton);
            app.default1.Visible = 'off';
            app.default1.Text = 'initial';
            app.default1.Value = true;
            app.ASButton = uiradiobutton(app.SequenceButton);
            app.ASButton.Text = 'A - S';
            app.SAButton = uiradiobutton(app.SequenceButton);
            app.SAButton.Text = 'S - A';
            app.RunExperimentButton = uibutton(app.UIFigure, 'push');
            app.RunExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButtonPushed, true);
            app.RunExperimentButton.Text = 'Run Experiment !';
            app.SelecttaskLabel = uilabel(app.UIFigure);
            app.SelecttaskLabel.Text = 'Select task';
            app.SelectTaskDropDown = uidropdown(app.UIFigure);
            app.SelectTaskDropDown.Items = {'select', 'TRAINING', 'TASK', 'Block 1 only', 'Block 2 only'};
            app.SelectTaskDropDown.ItemsData = {'999', 'Training', 'Task', 'Block1', 'Block2'};
            app.SelectTaskDropDown.Value = '999';
            app.JuiceLabel = uilabel(app.UIFigure);
            app.JuiceLabel.Text = 'Juice';
            app.SelectJuiceDropDown = uidropdown(app.UIFigure);
            app.SelectJuiceDropDown.Items = {'select', 'Orange', 'Apple', 'Multi', 'Pineapple', 'Grape'};
            app.SelectJuiceDropDown.ItemsData = {'999', 'O', 'A', 'M', 'N', 'T'};
            app.SelectJuiceDropDown.Value = '999';
            app.AlcoholLabel = uilabel(app.UIFigure);
            app.AlcoholLabel.Text = 'Alcohol';
            app.SelectAlcoholDropDown = uidropdown(app.UIFigure);
            app.SelectAlcoholDropDown.Items = {'select', 'Red wine', 'White wine', 'Berry liquor', 'Limoncello', 'Campari orange'};
            app.SelectAlcoholDropDown.ItemsData = {'999', 'R', 'W', 'F', 'L', 'C'};
            app.SelectAlcoholDropDown.Value = '999';
            app.Scanning = uibuttongroup(app.UIFigure);
            app.Scanning.Title = 'Scanning';
            app.noButton = uiradiobutton(app.Scanning);
            app.noButton.Text = 'no';
            app.noButton.Value = true;
            app.yesButton = uiradiobutton(app.Scanning);
            app.yesButton.Text = 'yes';
            app.ScannerLabel = uilabel(app.UIFigure);
            app.ScannerLabel.Text = 'Which Scanner';
            app.SelectScannerDropDown = uidropdown(app.UIFigure);
            app.SelectScannerDropDown.Items = {'select', 'MRI1', 'MRI2'};
            app.SelectScannerDropDown.ItemsData = {'999', '1', '2'};
            app.SelectScannerDropDown.Value = '999';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ILT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end